classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel_2           matlab.ui.container.Panel
        t_label           matlab.ui.control.Label
        clear             matlab.ui.control.Button
        stop              matlab.ui.control.Button
        plot              matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        t                 matlab.ui.control.NumericEditField
        tEditFieldLabel   matlab.ui.control.Label
        dt                matlab.ui.control.NumericEditField
        dtEditFieldLabel  matlab.ui.control.Label
        dx                matlab.ui.control.NumericEditField
        dxEditFieldLabel  matlab.ui.control.Label
        w                 matlab.ui.control.NumericEditField
        wLabel            matlab.ui.control.Label
        alpha             matlab.ui.control.NumericEditField
        alphaLabel        matlab.ui.control.Label
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        FTCS              matlab.ui.control.RadioButton
        FTBS              matlab.ui.control.RadioButton
        FTFS              matlab.ui.control.RadioButton
        ButtonGroup       matlab.ui.container.ButtonGroup
        task3             matlab.ui.control.RadioButton
        task2             matlab.ui.control.RadioButton
        task1             matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            global pluse;
            pluse=1;
            import ConvectionEq.*; %导入类模块
            alpha0 = app.alpha.Value;
            up_t = app.t.Value;
            w0 = app.w.Value;
            dx0 = app.dx.Value;
            dt0 = app.dt.Value;
            
            %确定边界条件类型
            if app.task1.Value==true
                b_t=1;
            elseif app.task2.Value==true
                b_t=2;
            elseif app.task3.Value==true
                b_t=3;
            end

            C = ConvectionEq(alpha0,1,up_t,0,0,w0);
            if app.FTFS.Value ==true
                X=C.FTFS(b_t,dt0,dx0,0);
            elseif app.FTBS.Value==true
                X=C.FTBS(b_t,dt0,dx0,0);
            elseif app.FTCS.Value==true
                X=C.FTCS(b_t,dt0,dx0,0);
            end
            tm = up_t/dt0;

            %设置标题与坐标
            if b_t==1
                if app.FTFS.Value ==true
                    title(app.UIAxes,'Task1-FTFS','FontSize',12,'FontWeight','bold');
                elseif app.FTBS.Value==true
                    title(app.UIAxes,'Task1-FTBS','FontSize',12,'FontWeight','bold');
                elseif app.FTCS.Value==true
                    title(app.UIAxes,'Task1-FTCS','FontSize',12,'FontWeight','bold');
                end
            elseif b_t==2
                if app.FTFS.Value ==true
                    title(app.UIAxes,'Task2-FTFS','FontSize',12,'FontWeight','bold');
                elseif app.FTBS.Value==true
                    title(app.UIAxes,'Task2-FTBS','FontSize',12,'FontWeight','bold');
                elseif app.FTCS.Value==true
                    title(app.UIAxes,'Task2-FTCS','FontSize',12,'FontWeight','bold');
                end
            elseif b_t==3
                if app.FTFS.Value ==true
                    title(app.UIAxes,'Task3-FTFS','FontSize',12,'FontWeight','bold');
                elseif app.FTBS.Value==true
                    title(app.UIAxes,'Task3-FTBS','FontSize',12,'FontWeight','bold');
                elseif app.FTCS.Value==true
                    title(app.UIAxes,'Task3-FTCS','FontSize',12,'FontWeight','bold');
                end
            end

            for i=1:tm+1
                app.t_label.Text=['t=',string((i-1)*dt0)];
                plot(app.UIAxes,X(:,i),'LineWidth',1.5,'Color','b','LineStyle','-');
                pause(0.1);
                
                if pluse==0
                    pause();
                end
            end
            

        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            global pluse;
            pluse=0;
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            cla(app.UIAxes);
            delete(app.UIAxes.Title)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '参数选择';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.Title = '题目选择';
            app.task1 = uiradiobutton(app.ButtonGroup);
            app.task1.Text = '题目1';
            app.task1.Value = true;
            app.task2 = uiradiobutton(app.ButtonGroup);
            app.task2.Text = '题目2';
            app.task3 = uiradiobutton(app.ButtonGroup);
            app.task3.Text = '题目3';
            app.ButtonGroup_2 = uibuttongroup(app.Panel);
            app.ButtonGroup_2.Title = '方法选择';
            app.FTFS = uiradiobutton(app.ButtonGroup_2);
            app.FTFS.Text = 'FTFS';
            app.FTFS.Value = true;
            app.FTBS = uiradiobutton(app.ButtonGroup_2);
            app.FTBS.Text = 'FTBS';
            app.FTCS = uiradiobutton(app.ButtonGroup_2);
            app.FTCS.Text = 'FTCS';
            app.alphaLabel = uilabel(app.Panel);
            app.alphaLabel.Text = '对流方程参数alpha';
            app.alpha = uieditfield(app.Panel, 'numeric');
            app.alpha.Value = 1;
            app.wLabel = uilabel(app.Panel);
            app.wLabel.Text = '角频率w';
            app.w = uieditfield(app.Panel, 'numeric');
            app.w.Value = 1;
            app.dxEditFieldLabel = uilabel(app.Panel);
            app.dxEditFieldLabel.Text = '位置步长dx';
            app.dx = uieditfield(app.Panel, 'numeric');
            app.dx.Value = 0.01;
            app.dtEditFieldLabel = uilabel(app.Panel);
            app.dtEditFieldLabel.Text = '时间步长dt';
            app.dt = uieditfield(app.Panel, 'numeric');
            app.dt.Value = 0.01;
            app.tEditFieldLabel = uilabel(app.Panel);
            app.tEditFieldLabel.Text = '时间上限t';
            app.t = uieditfield(app.Panel, 'numeric');
            app.t.Value = 5;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '绘图';
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.plot = uibutton(app.Panel_2, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Text = '绘图';
            app.stop = uibutton(app.Panel_2, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = '暂停';
            app.clear = uibutton(app.Panel_2, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清屏';
            app.t_label = uilabel(app.Panel_2);
            app.t_label.Text = 't=0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end